classdef app_layout < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        SerialPortDropDown    matlab.ui.control.DropDown
        SerialPortLabel       matlab.ui.control.Label
        RefreshPortsButton    matlab.ui.control.Button
        Image                 matlab.ui.control.Image
        TargetRPMSliderLabel  matlab.ui.control.Label
        TimeScope             matlab.ui.scope.TimeScope
        SimulationProgress    simulink.ui.control.SimulationProgress
        TargetRPMSlider       matlab.ui.control.Slider
        DirectionButtonGroup  matlab.ui.container.ButtonGroup
        ForwardButton         matlab.ui.control.RadioButton
        StopButton            matlab.ui.control.RadioButton
        ReverseButton         matlab.ui.control.RadioButton
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        IsRunning logical = false;
        CurrentSetPoint double = 0;
    end
    
    methods (Access = private)
        
        function synchronize(app)
            % Update components access
            app.TargetRPMSlider.Enable = app.IsRunning;
            app.DirectionButtonGroup.Enable = app.IsRunning;
            app.ForwardButton.Enable = app.IsRunning;
            app.StopButton.Enable = app.IsRunning;
            app.ReverseButton.Enable = app.IsRunning;

            if ~app.IsRunning
                app.DirectionButtonGroup.SelectedObject = app.StopButton;
            end

            % GUI -> Simulink
            if app.IsRunning
                switch app.DirectionButtonGroup.SelectedObject
                    case app.ReverseButton
                        app.CurrentSetPoint = -app.TargetRPMSlider.Value;
                    case app.StopButton
                        app.CurrentSetPoint = 0;
                    case app.ForwardButton
                        app.CurrentSetPoint = app.TargetRPMSlider.Value;
                end
                set_param("app_logic/Set Point", "Value", ...
                          string(app.CurrentSetPoint));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RefreshPortsButton
        function onRefreshClicked(app, event)
            available_ports = serialportlist("available");
            if isempty(available_ports); return; end
            app.SerialPortDropDown.Items = ["" available_ports];
            app.SerialPortDropDown.ItemsData = ["" available_ports];
            app.SerialPortDropDown.Value = "";
            app.SerialPortDropDown.Enable = true;
        end

        % Value changed function: SerialPortDropDown
        function onSerialPortChanged(app, event)
            value = app.SerialPortDropDown.Value;
            app.IsRunning = false;
            synchronize(app)
            
            if value == ""
                if app.Simulation.Status == "running"
                    app.Simulation.pause
                end
                return
            end
            
            try
                set_param("app_logic/Serial Configuration", ...
                          "Port", value);
                set_param("app_logic/Serial Receive", ...
                          "Port", value);
                set_param("app_logic/Serial Send", ...
                          "Port", value);
                if app.Simulation.Status ~= "running"
                    app.Simulation.start
                elseif app.Simulation.Status == "paused"
                    app.Simulation.resume
                end
                app.IsRunning = true;
                synchronize(app)
            catch error_message
                uialert(app.UIFigure, error_message.message, ...
                        "Cannot use the serial port")
                app.SerialPortDropDown.Value = "";
                app.CurrentSetPoint = "";
            end
        end

        % Selection changed function: DirectionButtonGroup
        function onDirectionChanged(app, event)
            synchronize(app)
        end

        % Callback function: TargetRPMSlider, TargetRPMSlider
        function onSliderChanging(app, event)
            synchronize(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DirectionButtonGroup = uibuttongroup(app.GridLayout);
            app.DirectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @onDirectionChanged, true);
            app.DirectionButtonGroup.Title = 'Direction';
            app.ReverseButton = uiradiobutton(app.DirectionButtonGroup);
            app.ReverseButton.Enable = 'off';
            app.ReverseButton.Text = 'Reverse';
            app.StopButton = uiradiobutton(app.DirectionButtonGroup);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.StopButton.Value = true;
            app.ForwardButton = uiradiobutton(app.DirectionButtonGroup);
            app.ForwardButton.Enable = 'off';
            app.ForwardButton.Text = 'Forward';
            app.TargetRPMSlider = uislider(app.GridLayout);
            app.TargetRPMSlider.Limits = [0 800];
            app.TargetRPMSlider.ValueChangedFcn = createCallbackFcn(app, @onSliderChanging, true);
            app.TargetRPMSlider.ValueChangingFcn = createCallbackFcn(app, @onSliderChanging, true);
            app.TargetRPMSlider.Enable = 'off';
            app.TargetRPMSlider.Value = 600;
            app.SimulationProgress = uisimprogress(app.GridLayout);
            app.SimulationProgress.Simulation = app.Simulation;
            app.TimeScope = uitimescope(app.GridLayout);
            app.TimeScope.LegendNames = {'Actual RPM'; 'Targeted RPM'};
            app.TimeScope.YLabel = 'RPM';
            app.TimeScope.XLimits = [0 1];
            app.TimeScope.YLimits = [-30 800];
            app.TimeScope.YLimitsMethod = 'manual';
            app.TimeScope.XTimeSpan = 1;
            app.TargetRPMSliderLabel = uilabel(app.GridLayout);
            app.TargetRPMSliderLabel.Enable = 'off';
            app.TargetRPMSliderLabel.Text = {'Target'; 'RPM'};
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.jpg');
            app.RefreshPortsButton = uibutton(app.GridLayout, 'push');
            app.RefreshPortsButton.ButtonPushedFcn = createCallbackFcn(app, @onRefreshClicked, true);
            app.RefreshPortsButton.Text = 'Refresh Ports';
            app.SerialPortLabel = uilabel(app.GridLayout);
            app.SerialPortLabel.Enable = 'off';
            app.SerialPortLabel.Text = 'Serial Port:';
            app.SerialPortDropDown = uidropdown(app.GridLayout);
            app.SerialPortDropDown.Items = {''};
            app.SerialPortDropDown.ValueChangedFcn = createCallbackFcn(app, @onSerialPortChanged, true);
            app.SerialPortDropDown.Enable = 'off';
            app.SerialPortDropDown.Placeholder = '(Click "Refresh Ports" button first)';
            app.SerialPortDropDown.Value = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Abs:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Smooth Signal:1'], app.TimeScope);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_layout

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Associate the Simulink Model
                app.Simulation = simulation('app_logic');

                % Create UIFigure and components
                createComponents(app)

                % Create bindings
                createBindings(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end